classdef RMSetParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RMparamsetUIFigure            matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        degMapFlitSigmaSliderLabel    matlab.ui.control.Label
        degMapFlitSigmaSlider         matlab.ui.control.Slider
        signMapFlitSigmaSliderLabel   matlab.ui.control.Label
        signMapFlitSigmaSlider        matlab.ui.control.Slider
        signMapThresholdSliderLabel   matlab.ui.control.Label
        signMapThresholdSlider        matlab.ui.control.Slider
        signMapErosionSliderLabel     matlab.ui.control.Label
        signMapErosionSlider          matlab.ui.control.Slider
        patchMapThresholdSliderLabel  matlab.ui.control.Label
        patchMapThresholdSlider       matlab.ui.control.Slider
        patchMapErosionSliderLabel    matlab.ui.control.Label
        patchMapErosionSlider         matlab.ui.control.Slider
        patchMapExtensionSliderLabel  matlab.ui.control.Label
        patchMapExtensionSlider       matlab.ui.control.Slider
        paramLabel                    matlab.ui.control.Label
        SignMappreviewLabel           matlab.ui.control.Label
        LoaddataButton                matlab.ui.control.Button
        saveparamButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        dataDir % data directory
        param % param
        degMap % Description
        savFlg
    end
    
    methods (Access = private)
        
        function paramUpdateFcn(app)
            app.param.degMapFltSigma = app.degMapFlitSigmaSlider.Value; % gaussian filter sigma
            app.param.signMapFltSigma = app.signMapFlitSigmaSlider.Value; % gaussian filter sigma
            app.param.signMapThreshold = app.signMapThresholdSlider.Value; % increase to include less areas
            app.param.signMapErosion = round(app.signMapErosionSlider.Value); % increase for smoother area
            app.param.patchMapThreshold = app.patchMapThresholdSlider.Value; % increase to include less areas
            app.param.patchMapErosion = round(app.patchMapErosionSlider.Value); % increase to include less areas
            app.param.patchMapExtention = round(app.patchMapExtensionSlider.Value); % increase to include more areas
            if ~isempty(app.degMap)
                app.plotFcn()
            end
        end
    
        
        function plotFcn(app)
            degMapAziFt = imgaussfilt(app.degMap.degMapAzi, app.param.degMapFltSigma);
            degMapElvFt = imgaussfilt(app.degMap.degMapElv, app.param.degMapFltSigma);
            [dhdx, dhdy] = gradient(degMapAziFt);
            [dvdx, dvdy] = gradient(degMapElvFt);
            graddirX = atan2(dhdy,dhdx);
            graddirY = atan2(dvdy,dvdx);
            vdiff = exp(1i*graddirX) .* exp(-1i*graddirY);
            signMapU = sin(angle(vdiff));
            signMapU(isnan(signMapU)==1) = 0;
            signMapFt = imgaussfilt(signMapU, app.param.signMapFltSigma);
            signMapThrd = signMapFt;
            signMapThreshold = (app.param.signMapThreshold * std(signMapFt(:)));
            signMapThrd(signMapThrd>=signMapThreshold) = 1;
            signMapThrd(signMapThrd<=-1*signMapThreshold) = -1;
            signMapThrd((signMapThrd>-1*signMapThreshold)&(signMapThrd<signMapThreshold)) = 0;
            signMapPos = signMapThrd;
            signMapPos(signMapPos ~= 1) = 0;
            signMapPos = imfill(signMapPos);
            signMapPos = imopen(signMapPos, strel('disk', app.param.signMapErosion, 0));
            signMapPos = imclose(signMapPos, strel('disk', app.param.signMapErosion, 0));
            signMapNeg = -1*signMapThrd;
            signMapNeg(signMapNeg ~= 1) = 0;
            signMapNeg = imfill(signMapNeg);
            signMapNeg = imopen(signMapNeg, strel('disk', app.param.signMapErosion, 0));
            signMapNeg = imclose(signMapNeg, strel('disk', app.param.signMapErosion, 0));
            signMapTz = signMapPos - signMapNeg;
            patchMapBz = imgaussfilt(signMapU, 1);
            patchThreshold = (app.param.patchMapThreshold * std(patchMapBz(:)));
            patchMapBz(patchMapBz>=patchThreshold) = 1;
            patchMapBz(patchMapBz<=-1*patchThreshold) = -1;
            patchMapBz((patchMapBz>-1*patchThreshold)&(patchMapBz<patchThreshold)) = 0;
            patchMapBz = abs(patchMapBz);
            imSiz = size(patchMapBz, 1);
            padWidth = round(imSiz/8);
            imL = [zeros(imSiz,padWidth) patchMapBz zeros(imSiz,padWidth)];
            imSiz = size(imL, 2);
            imL = [zeros(padWidth,imSiz); imL; zeros(padWidth,imSiz)];
            patchMapOp = imopen(imL,strel('disk',app.param.patchMapErosion, 0));
            patchMapCls = imclose(patchMapOp,strel('disk', app.param.patchMapExtention, 0));
            patchMapCls = imfill(patchMapCls);
            patchMapCls = imdilate(patchMapCls,strel('disk', 2, 0));
            patchMapCls = imfill(patchMapCls);
            patchMap = patchMapCls(padWidth+1:end-padWidth,padWidth+1:end-padWidth);
            labelMap = bwlabel(patchMap,4);
            patchList = unique(labelMap);
            patchSize = zeros(1,length(patchList));
            for iPatch = 1:length(patchList)
                patchIdx = find(labelMap == patchList(iPatch));
                patchSize(iPatch) = length(patchIdx);
            end
            patchSize(1) = 0;
            [~, mainPatches] = max(patchSize);
            patchMap(labelMap ~= patchList(mainPatches)) = 0;
            signMapTz = patchMap .* signMapTz;
            imagesc(app.UIAxes, signMapTz, [-1,1]);
            colormap(app.UIAxes, gray(3))
            axis(app.UIAxes,'tight')
            axis(app.UIAxes, 'equal')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, degMap)
            app.savFlg = 1;
            if exist("degMap","var")
                app.degMap = degMap;
                app.dataDir = degMap.savDir;
                app.savFlg = 0;
                paramUpdateFcn(app)
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [fname,fpath] = uigetfile('*.mat','select degMap file ...');
            drawnow;
            if fname ~= 0
                app.dataDir = fullfile(fpath, fname);
                app.degMap = load(app.dataDir);
                app.savFlg = 0;
                paramUpdateFcn(app)
            end
        end

        % Value changed function: degMapFlitSigmaSlider
        function degMapFlitSigmaSliderValueChanged(app, event)
            paramUpdateFcn(app)       
        end

        % Value changed function: signMapFlitSigmaSlider
        function signMapFlitSigmaSliderValueChanged(app, event)
            paramUpdateFcn(app) 
        end

        % Value changed function: signMapThresholdSlider
        function signMapThresholdSliderValueChanged(app, event)
            paramUpdateFcn(app)
        end

        % Value changed function: signMapErosionSlider
        function signMapErosionSliderValueChanged(app, event)
            paramUpdateFcn(app)    
        end

        % Value changed function: patchMapThresholdSlider
        function patchMapThresholdSliderValueChanged(app, event)
            paramUpdateFcn(app)     
        end

        % Value changed function: patchMapErosionSlider
        function patchMapErosionSliderValueChanged(app, event)
            paramUpdateFcn(app) 
        end

        % Value changed function: patchMapExtensionSlider
        function patchMapExtensionSliderValueChanged(app, event)
            paramUpdateFcn(app) 
        end

        % Button pushed function: saveparamButton
        function saveparamButtonPushed(app, event)
            param = app.param;
            if ~isempty(app.dataDir)
                try
                    save(app.dataDir,"param","-append")
                    disp("save succeed ...")
                    app.savFlg = 1;   
                catch
                    disp('cannot find path, save to the current folder')
                    save("param.mat","param")
                    app.savFlg = 1;
                end
             
            else
                disp("save fails: not dir ...")
            end
            
        end

        % Close request function: RMparamsetUIFigure
        function RMparamsetUIFigureCloseRequest(app, event)
            if ~app.savFlg
                warning("data not saved")
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RMparamsetUIFigure = uifigure('Visible', 'off');
            app.RMparamsetUIFigure.Name = 'RM param set';
            app.RMparamsetUIFigure.CloseRequestFcn = createCallbackFcn(app, @RMparamsetUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.RMparamsetUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.degMapFlitSigmaSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.degMapFlitSigmaSliderLabel.Text = 'degMapFlitSigma';
            app.degMapFlitSigmaSlider = uislider(app.RMparamsetUIFigure);
            app.degMapFlitSigmaSlider.Limits = [1 10];
            app.degMapFlitSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @degMapFlitSigmaSliderValueChanged, true);
            app.degMapFlitSigmaSlider.Value = 2;
            app.signMapFlitSigmaSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.signMapFlitSigmaSliderLabel.Text = 'signMapFlitSigma';
            app.signMapFlitSigmaSlider = uislider(app.RMparamsetUIFigure);
            app.signMapFlitSigmaSlider.Limits = [1 10];
            app.signMapFlitSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @signMapFlitSigmaSliderValueChanged, true);
            app.signMapFlitSigmaSlider.Value = 2;
            app.signMapThresholdSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.signMapThresholdSliderLabel.Text = 'signMapThreshold';
            app.signMapThresholdSlider = uislider(app.RMparamsetUIFigure);
            app.signMapThresholdSlider.Limits = [0 1];
            app.signMapThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @signMapThresholdSliderValueChanged, true);
            app.signMapThresholdSlider.Value = 0.6;
            app.signMapErosionSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.signMapErosionSliderLabel.Text = 'signMapErosion';
            app.signMapErosionSlider = uislider(app.RMparamsetUIFigure);
            app.signMapErosionSlider.Limits = [2 16];
            app.signMapErosionSlider.ValueChangedFcn = createCallbackFcn(app, @signMapErosionSliderValueChanged, true);
            app.signMapErosionSlider.Value = 2;
            app.patchMapThresholdSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.patchMapThresholdSliderLabel.Text = 'patchMapThreshold';
            app.patchMapThresholdSlider = uislider(app.RMparamsetUIFigure);
            app.patchMapThresholdSlider.Limits = [0 1];
            app.patchMapThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @patchMapThresholdSliderValueChanged, true);
            app.patchMapThresholdSlider.Value = 0.4;
            app.patchMapErosionSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.patchMapErosionSliderLabel.Text = 'patchMapErosion';
            app.patchMapErosionSlider = uislider(app.RMparamsetUIFigure);
            app.patchMapErosionSlider.Limits = [2 16];
            app.patchMapErosionSlider.ValueChangedFcn = createCallbackFcn(app, @patchMapErosionSliderValueChanged, true);
            app.patchMapErosionSlider.Value = 6;
            app.patchMapExtensionSliderLabel = uilabel(app.RMparamsetUIFigure);
            app.patchMapExtensionSliderLabel.Text = 'patchMapExtension';
            app.patchMapExtensionSlider = uislider(app.RMparamsetUIFigure);
            app.patchMapExtensionSlider.Limits = [2 16];
            app.patchMapExtensionSlider.ValueChangedFcn = createCallbackFcn(app, @patchMapExtensionSliderValueChanged, true);
            app.patchMapExtensionSlider.Value = 3;
            app.paramLabel = uilabel(app.RMparamsetUIFigure);
            app.paramLabel.Text = 'param.';
            app.SignMappreviewLabel = uilabel(app.RMparamsetUIFigure);
            app.SignMappreviewLabel.Text = 'SignMap preview';
            app.LoaddataButton = uibutton(app.RMparamsetUIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.saveparamButton = uibutton(app.RMparamsetUIFigure, 'push');
            app.saveparamButton.ButtonPushedFcn = createCallbackFcn(app, @saveparamButtonPushed, true);
            app.saveparamButton.Text = 'save param';
            % Show the figure after all components are created
            app.RMparamsetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMSetParam(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RMparamsetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RMparamsetUIFigure)
        end
    end
end